classdef MagLevTbx_HwConfig < matlab.apps.AppBase
    %MAGLEVTBX_HWCONFIG
    %   
    %   Riccardo Antonello (riccardo.antonello@unipd.it)
    %   
    %   March 25, 2026
    %   
    %   Dept. of Information Engineering, University of Padova 

    % Properties that correspond to app components
    properties (Access = public)
        MagLevTbxHwConfigUIFigure       matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        SelectthePCBversionButtonGroup  matlab.ui.container.ButtonGroup
        rb_v40                          matlab.ui.control.RadioButton
        rb_v43                          matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        
        group = "MagLevTbx"
        pref = "HwVersion"
         

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if ispref(app.group, app.pref)
                ver = getpref(app.group, app.pref);
                switch ver
                    case '4.0'
                        app.rb_v40.Value = true;
                    case '4.3'
                        app.rb_v40.Value = true;
                    otherwise
                        app.rb_v40.Value = true;
                end
            else
                app.rb_v40.Value = true;
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            %   find selected version
            if app.rb_v40.Value == true
                ver = '4.0';
            elseif app.rb_v43.Value == true
                ver = '4.3';
            else
                ver = '4.0';
            end

            %   set version
            setpref(app.group, app.pref, ver);

            %   exit
            uiresume(app.MagLevTbxHwConfigUIFigure);
            delete(app);

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            %   exit
            uiresume(app.MagLevTbxHwConfigUIFigure);
            delete(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MagLevTbxHwConfigUIFigure = uifigure('Visible', 'off');
            app.MagLevTbxHwConfigUIFigure.Name = 'MagLevTbx Hardware Config ';
            app.SelectthePCBversionButtonGroup = uibuttongroup(app.MagLevTbxHwConfigUIFigure);
            app.SelectthePCBversionButtonGroup.Title = 'Select the PCB version:';
            app.rb_v43 = uiradiobutton(app.SelectthePCBversionButtonGroup);
            app.rb_v43.Text = 'MagLev 4.3';
            app.rb_v40 = uiradiobutton(app.SelectthePCBversionButtonGroup);
            app.rb_v40.Text = 'MagLev 4.0';
            app.rb_v40.Value = true;
            app.OKButton = uibutton(app.MagLevTbxHwConfigUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.MagLevTbxHwConfigUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.MagLevTbxHwConfigUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagLevTbx_HwConfig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MagLevTbxHwConfigUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MagLevTbxHwConfigUIFigure)
        end
    end
end